classdef dc_plots < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        RunLAButton                 matlab.ui.control.Button
        SubADCSelectDropDownLabel   matlab.ui.control.Label
        SubADCSelectDropDown        matlab.ui.control.DropDown
        ADCSelectDropDownLabel      matlab.ui.control.Label
        ADCSelectDropDown           matlab.ui.control.DropDown
        SignalOverlayCheckBox       matlab.ui.control.CheckBox
        FrequencyHzEditFieldLabel   matlab.ui.control.Label
        FrequencyHzEditField        matlab.ui.control.EditField
        PhaseShiftSliderLabel       matlab.ui.control.Label
        PhaseShiftSlider            matlab.ui.control.Slider
        AmplitudeAttenuationSliderLabel  matlab.ui.control.Label
        AmplitudeAttenuationSlider  matlab.ui.control.Slider
        DCOffsetSliderLabel         matlab.ui.control.Label
        DCOffsetSlider              matlab.ui.control.Slider
    end

    
    properties (Access = private)
        ni845x; % Description
        LA;
        CS;
        adc_even;
        adc_odd;
        enableDCtest;
        enableSUBadcs;
        enableStatic;
        signal;
        new_signal; 
        clock;
        adcs;
        clock_div;
        sample_period;        
        vector_size;
        xvalues;
        sinx;       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Estabishes communication with peripherals
            app.ni845x = openNi845x();
            adc_num = app.ADCSelectDropDown.Value;
            LA_config = ['synchronous_sine_',adc_num , '.ala'];
            app.LA = openLA(LA_config);
            
            % Defines chips selects and resets the board
            app.CS.RC0A = 0;
            app.CS.RC0B = 1;
            app.CS.RC1B = 2;
            app.CS.RC1A = 3;
            app.CS.RFIC = 4;
            app.CS.RST = 5;
            load('calibration_variables.mat','adc_evens',"adc_odds");
            app.adc_even = adc_evens;
            app.adc_odd = adc_odds;           
            adc_str = ['ADC',adc_num , '_CONFIG_RAMP2.txt'];
            registerWrite(app.ni845x, adc_str, 'ADC')
            if strcmp(adc_num, '1')
                app.enableDCtest = '005F';
                app.enableSUBadcs = '0047';
                app.enableStatic = '0020';
                portConf = '0049';
            else
                app.enableDCtest = '0082';
                app.enableSUBadcs = '0048';
                app.enableStatic = '0021';
                portConf = '004A';
            end
            % data = dec2hex(5,4); % Test port Conf, set to 5 for 50 ohm termination, 4 otherwise 
            writeNi845x(app.ni845x, app.CS.RFIC, portConf, '0011');
            writeNi845x(app.ni845x, app.CS.RFIC, app.enableDCtest, '00FF');
            writeNi845x(app.ni845x, app.CS.RFIC, app.enableSUBadcs, '00FF');
            writeNi845x(app.ni845x, app.CS.RFIC, app.enableStatic, '0000');
            app.signal = getSignalClocked(app.LA, app.adc_even, app.adc_odd);
            app.signal.scaled = zeros(1, length(app.signal.constructed)*10);
            app.signal.scaled(app.signal.scaled==0) = nan;
            for i = 10:10:length(app.signal.scaled)
                app.signal.scaled(i) = app.signal.constructed(i/10);
            end
            plot(app.UIAxes, app.signal.scaled,'*')
            
            % Code for synthetic signal
            app.clock = 4*(10^9);
            app.adcs = 8;
            app.clock_div = 4;
            app.sample_period = app.clock^(-1) * app.adcs / app.clock_div;
            app.vector_size = length(app.signal.constructed);
            app.xvalues = 0:(app.sample_period/10):app.sample_period * app.vector_size;
        end

        % Value changed function: ADCSelectDropDown
        function ADCSelectDropDownValueChanged(app, event)
            adc_num = app.ADCSelectDropDown.Value;
            adc_str = ['ADC',adc_num , '_CONFIG_RAMP2.txt'];
            registerWrite(app.ni845x, adc_str, 'ADC')
            if strcmp(adc_num, '1')
                app.enableDCtest = '005F';
                app.enableSUBadcs = '0047';
                app.enableStatic = '0020';
            else
                app.enableDCtest = '0082';
                app.enableSUBadcs = '0048';
                app.enableStatic = '0021';
            end
        end

        % Button pushed function: RunLAButton
        function RunLAButtonPushed(app, event)
            subADC = str2double(app.SubADCSelectDropDown.Value);
            if subADC == 0
                turn_on_adc = '00FF';
                turn_off_adc = '0000';
                app.new_signal = [];
            else
                turn_on_adc = dec2hex(2^(subADC-1),4);
                turn_off_adc = dec2hex((255-2^(subADC-1)),4);
            end
            writeNi845x(app.ni845x, app.CS.RFIC, app.enableDCtest, turn_on_adc);
            writeNi845x(app.ni845x, app.CS.RFIC, app.enableSUBadcs, turn_on_adc);
            writeNi845x(app.ni845x, app.CS.RFIC, app.enableStatic, turn_off_adc);
            app.signal = getSignalClocked(app.LA, app.adc_even, app.adc_odd);
            app.signal.scaled = zeros(1, length(app.signal.constructed)*10);
            app.signal.scaled(app.signal.scaled==0) = nan;
            for i = 10:10:length(app.signal.scaled)
                app.signal.scaled(i) = app.signal.constructed(i/10);
            end
            if subADC == 0
                plot(app.UIAxes, app.signal.scaled,'*')
            else
                app.new_signal = zeros(1, length(app.signal.scaled));
                app.new_signal(app.new_signal==0) = nan;
                for i = subADC*10:80:length(app.signal.scaled)
                    app.new_signal(i) = app.signal.scaled(i);
                end
                plot(app.UIAxes, app.new_signal, '*')
            end
            
        end

        % Value changed function: SubADCSelectDropDown
        function SubADCSelectDropDownValueChanged(app, event)
            subADC = str2double(app.SubADCSelectDropDown.Value);
            show_signal = app.SignalOverlayCheckBox.Value;
            if subADC == 0
                plot(app.UIAxes, app.signal.scaled,'*')
                app.new_signal = [];
            else
                app.new_signal = zeros(1, length(app.signal.scaled));
                app.new_signal(app.new_signal==0) = nan;
                for i = subADC*10:80:length(app.signal.scaled)
                    app.new_signal(i) = app.signal.scaled(i);
                end
                plot(app.UIAxes, app.new_signal, '*')
            end
            if show_signal
                hold(app.UIAxes, 'on')
                plot(app.UIAxes, app.sinx, ':')
                hold(app.UIAxes, 'off')
            end
        end

        % Value changed function: SignalOverlayCheckBox
        function SignalOverlayCheckBoxValueChanged(app, event)
            show_signal = app.SignalOverlayCheckBox.Value;
            if show_signal && isempty(app.sinx)
                signal_freq = 2*pi*str2double(app.FrequencyHzEditField.Value);
                phase = app.PhaseShiftSlider.Value;
                A = 1 - app.AmplitudeAttenuationSlider.Value;
                app.sinx = 2048 * A * sin(app.xvalues.*signal_freq + phase) + 2048;
            end
            if isempty(app.new_signal)
                plot(app.UIAxes, app.signal.scaled,'*')
            else
                plot(app.UIAxes, app.new_signal,'*')
            end
            if show_signal
                hold(app.UIAxes, 'on')
                plot(app.UIAxes, app.sinx)
                hold(app.UIAxes, 'off')
            end
        end

        % Value changed function: FrequencyHzEditField
        function FrequencyHzEditFieldValueChanged(app, event)
            signal_freq = 2*pi*str2double(app.FrequencyHzEditField.Value);
            phase = app.PhaseShiftSlider.Value;
            A = 1 - app.AmplitudeAttenuationSlider.Value;
            app.sinx = 2048 * A * sin(app.xvalues.*signal_freq + phase) + 2048;
            show_signal = app.SignalOverlayCheckBox.Value;
            if show_signal
                if isempty(app.new_signal)
                    plot(app.UIAxes, app.signal.scaled,'*')
                else
                    plot(app.UIAxes, app.new_signal,'*')
                end
                hold(app.UIAxes, 'on')
                plot(app.UIAxes, app.sinx, ':')
                hold(app.UIAxes, 'off')
            end
        end

        % Value changed function: PhaseShiftSlider
        function PhaseShiftSliderValueChanged(app, event)
            signal_freq = 2*pi*str2double(app.FrequencyHzEditField.Value);
            phase = app.PhaseShiftSlider.Value;
            A = 1 - app.AmplitudeAttenuationSlider.Value;
            app.sinx = 2048 * A * sin(app.xvalues.*signal_freq + phase) + 2048;
            show_signal = app.SignalOverlayCheckBox.Value;
            if show_signal
                if isempty(app.new_signal)
                    plot(app.UIAxes, app.signal.scaled,'*')
                else
                    plot(app.UIAxes, app.new_signal,'*')
                end
                hold(app.UIAxes, 'on')
                plot(app.UIAxes, app.sinx, ':')
                hold(app.UIAxes, 'off')
            end
        end

        % Value changed function: AmplitudeAttenuationSlider
        function AmplitudeAttenuationSliderValueChanged(app, event)
            signal_freq = 2*pi*str2double(app.FrequencyHzEditField.Value);
            phase = app.PhaseShiftSlider.Value;
            A = 1 - app.AmplitudeAttenuationSlider.Value;
            app.sinx = 2048 * A * sin(app.xvalues.*signal_freq + phase) + 2048;
            show_signal = app.SignalOverlayCheckBox.Value;
            if show_signal
                if isempty(app.new_signal)
                    plot(app.UIAxes, app.signal.scaled,'*')
                else
                    plot(app.UIAxes, app.new_signal,'*')
                end
                hold(app.UIAxes, 'on')
                plot(app.UIAxes, app.sinx, ':')
                hold(app.UIAxes, 'off')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'DC Sine Wave')
            xlabel(app.UIAxes, 'Samples')
            ylabel(app.UIAxes, 'SubADC Values')
            app.UIAxes.YLim = [0 4095];
            app.RunLAButton = uibutton(app.UIFigure, 'push');
            app.RunLAButton.ButtonPushedFcn = createCallbackFcn(app, @RunLAButtonPushed, true);
            app.RunLAButton.Text = 'Run LA';
            app.SubADCSelectDropDownLabel = uilabel(app.UIFigure);
            app.SubADCSelectDropDownLabel.Text = 'SubADC Select';
            app.SubADCSelectDropDown = uidropdown(app.UIFigure);
            app.SubADCSelectDropDown.Items = {'All', 'SubADC1', 'SubADC2', 'SubADC3', 'SubADC4', 'SubADC5', 'SubADC6', 'SubADC7', 'SubADC8'};
            app.SubADCSelectDropDown.ItemsData = {'0', '1', '2', '3', '4', '5', '6', '7', '8'};
            app.SubADCSelectDropDown.ValueChangedFcn = createCallbackFcn(app, @SubADCSelectDropDownValueChanged, true);
            app.SubADCSelectDropDown.Value = '0';
            app.ADCSelectDropDownLabel = uilabel(app.UIFigure);
            app.ADCSelectDropDownLabel.Text = 'ADC Select';
            app.ADCSelectDropDown = uidropdown(app.UIFigure);
            app.ADCSelectDropDown.Items = {'1', '2'};
            app.ADCSelectDropDown.ValueChangedFcn = createCallbackFcn(app, @ADCSelectDropDownValueChanged, true);
            app.ADCSelectDropDown.Value = '1';
            app.SignalOverlayCheckBox = uicheckbox(app.UIFigure);
            app.SignalOverlayCheckBox.ValueChangedFcn = createCallbackFcn(app, @SignalOverlayCheckBoxValueChanged, true);
            app.SignalOverlayCheckBox.Text = 'Signal Overlay';
            app.FrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzEditField = uieditfield(app.UIFigure, 'text');
            app.FrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyHzEditFieldValueChanged, true);
            app.FrequencyHzEditField.Value = '10e3';
            app.PhaseShiftSliderLabel = uilabel(app.UIFigure);
            app.PhaseShiftSliderLabel.Text = 'Phase Shift';
            app.PhaseShiftSlider = uislider(app.UIFigure);
            app.PhaseShiftSlider.Limits = [0 6.28318530717959];
            app.PhaseShiftSlider.MajorTicks = [0 0.523598775598299 0.785398163397448 1.0471975511966 1.5707963267949 2.0943951023932 2.35619449019234 2.61799387799149 3.14159265358979 3.66519142918809 3.92699081698724 4.18879020478639 4.71238898038469 5.23598775598299 5.49778714378214 5.75958653158129 6.28318530717959];
            app.PhaseShiftSlider.MajorTickLabels = {'0', 'π/6', 'π/4', 'π/3', 'π/2', '2π/3  ', '3π/4  ', '5π/6', 'π', '7π/6', '5π/4', '4π/3', '3π/2', '5π/3', '7π/4', '11π/6', '2π'};
            app.PhaseShiftSlider.ValueChangedFcn = createCallbackFcn(app, @PhaseShiftSliderValueChanged, true);
            app.PhaseShiftSlider.MinorTicks = [];
            app.AmplitudeAttenuationSliderLabel = uilabel(app.UIFigure);
            app.AmplitudeAttenuationSliderLabel.Text = 'Amplitude Attenuation';
            app.AmplitudeAttenuationSlider = uislider(app.UIFigure);
            app.AmplitudeAttenuationSlider.Limits = [0 1];
            app.AmplitudeAttenuationSlider.ValueChangedFcn = createCallbackFcn(app, @AmplitudeAttenuationSliderValueChanged, true);
            app.DCOffsetSliderLabel = uilabel(app.UIFigure);
            app.DCOffsetSliderLabel.Text = 'DC Offset';
            app.DCOffsetSlider = uislider(app.UIFigure);
            app.DCOffsetSlider.Limits = [-1 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dc_plots

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end