classdef readWriteGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Switch_0                 matlab.ui.control.ToggleSwitch
        LampW_0                  matlab.ui.control.Lamp
        LampR_0                  matlab.ui.control.Lamp
        Label_0                  matlab.ui.control.Label
        LampW_1                  matlab.ui.control.Lamp
        LampR_1                  matlab.ui.control.Lamp
        Label_1                  matlab.ui.control.Label
        LampW_2                  matlab.ui.control.Lamp
        LampR_2                  matlab.ui.control.Lamp
        Label_2                  matlab.ui.control.Label
        LampW_3                  matlab.ui.control.Lamp
        LampR_3                  matlab.ui.control.Lamp
        Label_3                  matlab.ui.control.Label
        LampW_4                  matlab.ui.control.Lamp
        LampR_4                  matlab.ui.control.Lamp
        Label_4                  matlab.ui.control.Label
        LampW_5                  matlab.ui.control.Lamp
        LampR_5                  matlab.ui.control.Lamp
        Label_5                  matlab.ui.control.Label
        LampW_6                  matlab.ui.control.Lamp
        LampR_6                  matlab.ui.control.Lamp
        Label_6                  matlab.ui.control.Label
        LampW_7                  matlab.ui.control.Lamp
        LampR_7                  matlab.ui.control.Lamp
        Label_7                  matlab.ui.control.Label
        LampW_8                  matlab.ui.control.Lamp
        LampR_8                  matlab.ui.control.Lamp
        Label_8                  matlab.ui.control.Label
        LampW_9                  matlab.ui.control.Lamp
        LampR_9                  matlab.ui.control.Lamp
        Label_9                  matlab.ui.control.Label
        LampW_10                 matlab.ui.control.Lamp
        LampR_10                 matlab.ui.control.Lamp
        Label_10                 matlab.ui.control.Label
        LampW_11                 matlab.ui.control.Lamp
        LampR_11                 matlab.ui.control.Lamp
        Label_11                 matlab.ui.control.Label
        LampW_12                 matlab.ui.control.Lamp
        LampR_12                 matlab.ui.control.Lamp
        Label_12                 matlab.ui.control.Label
        LampW_13                 matlab.ui.control.Lamp
        LampR_13                 matlab.ui.control.Lamp
        Label_13                 matlab.ui.control.Label
        LampW_14                 matlab.ui.control.Lamp
        LampR_14                 matlab.ui.control.Lamp
        Label_14                 matlab.ui.control.Label
        LampW_15                 matlab.ui.control.Lamp
        LampR_15                 matlab.ui.control.Lamp
        Label_15                 matlab.ui.control.Label
        ReadButton               matlab.ui.control.Button
        FileWriteButton          matlab.ui.control.Button
        ChipSelectDropDownLabel  matlab.ui.control.Label
        ChipSelectDropDown       matlab.ui.control.DropDown
        EditFieldWrite           matlab.ui.control.EditField
        ADDRESSEditFieldLabel    matlab.ui.control.Label
        ADDRESSEditField         matlab.ui.control.EditField
        DATALabel                matlab.ui.control.Label
        RETURNLabel              matlab.ui.control.Label
        FilenameEditFieldLabel   matlab.ui.control.Label
        FilenameEditField        matlab.ui.control.EditField
        Switch_1                 matlab.ui.control.ToggleSwitch
        Switch_2                 matlab.ui.control.ToggleSwitch
        Switch_3                 matlab.ui.control.ToggleSwitch
        Switch_4                 matlab.ui.control.ToggleSwitch
        Switch_5                 matlab.ui.control.ToggleSwitch
        Switch_6                 matlab.ui.control.ToggleSwitch
        Switch_7                 matlab.ui.control.ToggleSwitch
        Switch_8                 matlab.ui.control.ToggleSwitch
        Switch_9                 matlab.ui.control.ToggleSwitch
        Switch_10                matlab.ui.control.ToggleSwitch
        Switch_11                matlab.ui.control.ToggleSwitch
        Switch_12                matlab.ui.control.ToggleSwitch
        Switch_13                matlab.ui.control.ToggleSwitch
        Switch_14                matlab.ui.control.ToggleSwitch
        Switch_15                matlab.ui.control.ToggleSwitch
        EditFieldWriteDecimal    matlab.ui.control.NumericEditField
        Read                     matlab.ui.control.Label
        ReadDecimal              matlab.ui.control.Label
        WriteButton              matlab.ui.control.Button
    end

    
    properties (Access = public)
        ni845x;
        cs;
        address;
    end
    
    methods (Access = private)
        function add_bits(app)
            bit0 = str2double(app.Switch_0.Value)*2^0;
            bit1 = str2double(app.Switch_1.Value)*2^1;
            bit2 = str2double(app.Switch_2.Value)*2^2;
            bit3 = str2double(app.Switch_3.Value)*2^3;
            bit4 = str2double(app.Switch_4.Value)*2^4;
            bit5 = str2double(app.Switch_5.Value)*2^5;
            bit6 = str2double(app.Switch_6.Value)*2^6;
            bit7 = str2double(app.Switch_7.Value)*2^7;
            bit8 = str2double(app.Switch_8.Value)*2^8;
            bit9 = str2double(app.Switch_9.Value)*2^9;
            bit10 = str2double(app.Switch_10.Value)*2^10;
            bit11 = str2double(app.Switch_11.Value)*2^11;
            bit12 = str2double(app.Switch_12.Value)*2^12;
            bit13 = str2double(app.Switch_13.Value)*2^13;
            bit14 = str2double(app.Switch_14.Value)*2^14;
            bit15 = str2double(app.Switch_15.Value)*2^15;
            data = bit0 + bit1 + bit2 + bit3 + bit4 + bit5 + bit6 + bit7 + bit8 + bit9 + bit10 + bit11 + bit12 + bit13 + bit14 + bit15;
            app.EditFieldWrite.Value = dec2hex(data,4);
            app.EditFieldWriteDecimal.Value = data;
        end
        
        function light_write_lamps(app, data)
            if (data - 2^15) >= 0
                data = data - 2^15;
                app.Switch_15.Value = '1';
                app.LampW_15.Color = [0,1,0];
            else
                app.Switch_15.Value = '0';
                app.LampW_15.Color = [0.47,0.67,0.19];
            end
            if (data - 2^14) >= 0
                data = data - 2^14;
                app.Switch_14.Value = '1';
                app.LampW_14.Color = [0,1,0];
            else
                app.Switch_14.Value = '0';
                app.LampW_14.Color = [0.47,0.67,0.19];
            end
            if (data - 2^13) >= 0
                data = data - 2^13;
                app.Switch_13.Value = '1';
                app.LampW_13.Color = [0,1,0];
            else
                app.Switch_13.Value = '0';
                app.LampW_13.Color = [0.47,0.67,0.19];
            end
            if (data - 2^12) >= 0
                data = data - 2^12;
                app.Switch_12.Value = '1';
                app.LampW_12.Color = [0,1,0];
            else
                app.Switch_12.Value = '0';
                app.LampW_12.Color = [0.47,0.67,0.19];
            end
            if (data - 2^11) >= 0
                data = data - 2^11;
                app.Switch_11.Value = '1';
                app.LampW_11.Color = [0,1,0];
            else
                app.Switch_11.Value = '0';
                app.LampW_11.Color = [0.47,0.67,0.19];
            end
            if (data - 2^10) >= 0
                data = data - 2^10;
                app.Switch_10.Value = '1';
                app.LampW_10.Color = [0,1,0];
            else
                app.Switch_10.Value = '0';
                app.LampW_10.Color = [0.47,0.67,0.19];
            end
            if (data - 2^9) >= 0
                data = data - 2^9;
                app.Switch_9.Value = '1';
                app.LampW_9.Color = [0,1,0];
            else
                app.Switch_9.Value = '0';
                app.LampW_9.Color = [0.47,0.67,0.19];
            end
            if (data - 2^8) >= 0
                data = data - 2^8;
                app.Switch_8.Value = '1';
                app.LampW_8.Color = [0,1,0];
            else
                app.Switch_8.Value = '0';
                app.LampW_8.Color = [0.47,0.67,0.19];
            end
            if (data - 2^7) >= 0
                data = data - 2^7;
                app.Switch_7.Value = '1';
                app.LampW_7.Color = [0,1,0];
            else
                app.Switch_7.Value = '0';
                app.LampW_7.Color = [0.47,0.67,0.19];
            end
            if (data - 2^6) >= 0
                data = data - 2^6;
                app.Switch_6.Value = '1';
                app.LampW_6.Color = [0,1,0];
            else
                app.Switch_6.Value = '0';
                app.LampW_6.Color = [0.47,0.67,0.19];
            end
            if (data - 2^5) >= 0
                data = data - 2^5;
                app.Switch_5.Value = '1';
                app.LampW_5.Color = [0,1,0];
            else
                app.Switch_5.Value = '0';
                app.LampW_5.Color = [0.47,0.67,0.19];
            end
            if (data - 2^4) >= 0
                data = data - 2^4;
                app.Switch_4.Value = '1';
                app.LampW_4.Color = [0,1,0];
            else
                app.Switch_4.Value = '0';
                app.LampW_4.Color = [0.47,0.67,0.19];
            end
            if (data - 2^3) >= 0
                data = data - 2^3;
                app.Switch_3.Value = '1';
                app.LampW_3.Color = [0,1,0];
            else
                app.Switch_3.Value = '0';
                app.LampW_3.Color = [0.47,0.67,0.19];
            end
            if (data - 2^2) >= 0
                data = data - 2^2;
                app.Switch_2.Value = '1';
                app.LampW_2.Color = [0,1,0];
            else
                app.Switch_2.Value = '0';
                app.LampW_2.Color = [0.47,0.67,0.19];
            end
            if (data - 2^1) >= 0
                data = data - 2^1;
                app.Switch_1.Value = '1';
                app.LampW_1.Color = [0,1,0];
            else
                app.Switch_1.Value = '0';
                app.LampW_1.Color = [0.47,0.67,0.19];
            end
            if (data - 2^0) >= 0
                app.Switch_0.Value = '1';
                app.LampW_0.Color = [0,1,0];
            else
                app.Switch_0.Value = '0';
                app.LampW_0.Color = [0.47,0.67,0.19];
            end
        end
        
        function light_read_lamps(app, data)
            if (data - 2^15) >= 0
                data = data - 2^15;
                app.LampR_15.Color = [0,1,0];
            else
                app.LampR_15.Color = [0.47,0.67,0.19];
            end
            if (data - 2^14) >= 0
                data = data - 2^14;
                app.LampR_14.Color = [0,1,0];
            else
                app.LampR_14.Color = [0.47,0.67,0.19];
            end
            if (data - 2^13) >= 0
                data = data - 2^13;
                app.LampR_13.Color = [0,1,0];
            else
                app.LampR_13.Color = [0.47,0.67,0.19];
            end
            if (data - 2^12) >= 0
                data = data - 2^12;
                app.LampR_12.Color = [0,1,0];
            else
                app.LampR_12.Color = [0.47,0.67,0.19];
            end
            if (data - 2^11) >= 0
                data = data - 2^11;
                app.LampR_11.Color = [0,1,0];
            else
                app.LampR_11.Color = [0.47,0.67,0.19];
            end
            if (data - 2^10) >= 0
                data = data - 2^10;
                app.LampR_10.Color = [0,1,0];
            else
                app.LampR_10.Color = [0.47,0.67,0.19];
            end
            if (data - 2^9) >= 0
                data = data - 2^9;
                app.LampR_9.Color = [0,1,0];
            else
                app.LampR_9.Color = [0.47,0.67,0.19];
            end
            if (data - 2^8) >= 0
                data = data - 2^8;
                app.LampR_8.Color = [0,1,0];
            else
                app.LampR_8.Color = [0.47,0.67,0.19];
            end
            if (data - 2^7) >= 0
                data = data - 2^7;
                app.LampR_7.Color = [0,1,0];
            else
                app.LampR_7.Color = [0.47,0.67,0.19];
            end
            if (data - 2^6) >= 0
                data = data - 2^6;
                app.LampR_6.Color = [0,1,0];
            else
                app.LampR_6.Color = [0.47,0.67,0.19];
            end
            if (data - 2^5) >= 0
                data = data - 2^5;
                app.LampR_5.Color = [0,1,0];
            else
                app.LampR_5.Color = [0.47,0.67,0.19];
            end
            if (data - 2^4) >= 0
                data = data - 2^4;
                app.LampR_4.Color = [0,1,0];
            else
                app.LampR_4.Color = [0.47,0.67,0.19];
            end
            if (data - 2^3) >= 0
                data = data - 2^3;
                app.LampR_3.Color = [0,1,0];
            else
                app.LampR_3.Color = [0.47,0.67,0.19];
            end
            if (data - 2^2) >= 0
                data = data - 2^2;
                app.LampR_2.Color = [0,1,0];
            else
                app.LampR_2.Color = [0.47,0.67,0.19];
            end
            if (data - 2^1) >= 0
                data = data - 2^1;
                app.LampR_1.Color = [0,1,0];
            else
                app.LampR_1.Color = [0.47,0.67,0.19];
            end
            if (data - 2^0) >= 0
                app.LampR_0.Color = [0,1,0];
            else
                app.LampR_0.Color = [0.47,0.67,0.19];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ni845x = openNi845x();
            app.cs = str2double(app.ChipSelectDropDown.Value);
            app.address = app.ADDRESSEditField.Value;
        end

        % Value changed function: Switch_0
        function Switch_0ValueChanged(app, event)
            switch_on = app.Switch_0.Value;
            if switch_on == '1'
                app.LampW_0.Color = [0,1,0];
            else
                app.LampW_0.Color = [0.47,0.67,0.19];
            end
            add_bits(app);
        end

        % Button pushed function: FileWriteButton
        function FileWriteButtonPushed(app, event)
            [file, path] = uigetfile('*.txt');
            file_name = fullfile(path,file);
            if ~isequal(file,0)
                registerWriteGui(app.ni845x,file_name,app.cs);
            end
        end

        % Value changed function: Switch_1
        function Switch_1ValueChanged(app, event)
            switch_on = app.Switch_1.Value;
            if switch_on == '1'
                app.LampW_1.Color = [0,1,0];
            else
                app.LampW_1.Color = [0.47,0.67,0.19];
            end
            add_bits(app);
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            switch_on = app.Switch_2.Value;
            if switch_on == '1'
                app.LampW_2.Color = [0,1,0];
            else
                app.LampW_2.Color = [0.47,0.67,0.19];
            end
            add_bits(app);
        end

        % Value changed function: Switch_3
        function Switch_3ValueChanged(app, event)
            switch_on = app.Switch_3.Value;
            if switch_on == '1'
                app.LampW_3.Color = [0,1,0];
            else
                app.LampW_3.Color = [0.47,0.67,0.19];
            end
            add_bits(app);
        end

        % Value changed function: Switch_4
        function Switch_4ValueChanged(app, event)
            switch_on = app.Switch_4.Value;
            if switch_on == '1'
                app.LampW_4.Color = [0,1,0];
            else
                app.LampW_4.Color = [0.47,0.67,0.19];
            end
            add_bits(app);
        end

        % Value changed function: Switch_5
        function Switch_5ValueChanged(app, event)
            switch_on = app.Switch_5.Value;
            if switch_on == '1'
                app.LampW_5.Color = [0,1,0];
            else
                app.LampW_5.Color = [0.47,0.67,0.19];
            end
            add_bits(app);
        end

        % Value changed function: Switch_6
        function Switch_6ValueChanged(app, event)
            switch_on = app.Switch_6.Value;
            if switch_on == '1'
                app.LampW_6.Color = [0,1,0];
            else
                app.LampW_6.Color = [0.47,0.67,0.19];
            end
            add_bits(app);
        end

        % Value changed function: Switch_7
        function Switch_7ValueChanged(app, event)
            switch_on = app.Switch_7.Value;
            if switch_on == '1'
                app.LampW_7.Color = [0,1,0];
            else
                app.LampW_7.Color = [0.47,0.67,0.19];
            end
            add_bits(app);
        end

        % Value changed function: Switch_8
        function Switch_8ValueChanged(app, event)
            switch_on = app.Switch_8.Value;
            if switch_on == '1'
                app.LampW_8.Color = [0,1,0];
            else
                app.LampW_8.Color = [0.47,0.67,0.19];
            end
            add_bits(app);
        end

        % Value changed function: Switch_9
        function Switch_9ValueChanged(app, event)
            switch_on = app.Switch_9.Value;
            if switch_on == '1'
                app.LampW_9.Color = [0,1,0];
            else
                app.LampW_9.Color = [0.47,0.67,0.19];
            end
            add_bits(app);
        end

        % Value changed function: Switch_10
        function Switch_10ValueChanged(app, event)
            switch_on = app.Switch_10.Value;
            if switch_on == '1'
                app.LampW_10.Color = [0,1,0];
            else
                app.LampW_10.Color = [0.47,0.67,0.19];
            end
            add_bits(app);
        end

        % Value changed function: Switch_11
        function Switch_11ValueChanged(app, event)
            switch_on = app.Switch_11.Value;
            if switch_on == '1'
                app.LampW_11.Color = [0,1,0];
            else
                app.LampW_11.Color = [0.47,0.67,0.19];
            end
            add_bits(app);
        end

        % Value changed function: Switch_12
        function Switch_12ValueChanged(app, event)
            switch_on = app.Switch_12.Value;
            if switch_on == '1'
                app.LampW_12.Color = [0,1,0];
            else
                app.LampW_12.Color = [0.47,0.67,0.19];
            end
            add_bits(app);
        end

        % Value changed function: Switch_13
        function Switch_13ValueChanged(app, event)
            switch_on = app.Switch_13.Value;
            if switch_on == '1'
                app.LampW_13.Color = [0,1,0];
            else
                app.LampW_13.Color = [0.47,0.67,0.19];
            end
            add_bits(app);
        end

        % Value changed function: Switch_14
        function Switch_14ValueChanged(app, event)
            switch_on = app.Switch_14.Value;
            if switch_on == '1'
                app.LampW_14.Color = [0,1,0];
            else
                app.LampW_14.Color = [0.47,0.67,0.19];
            end
            add_bits(app);
        end

        % Value changed function: Switch_15
        function Switch_15ValueChanged(app, event)
            switch_on = app.Switch_15.Value;
            if switch_on == '1'
                app.LampW_15.Color = [0,1,0];
            else
                app.LampW_15.Color = [0.47,0.67,0.19];
            end
            add_bits(app);
        end

        % Value changed function: EditFieldWrite
        function EditFieldWriteValueChanged(app, event)
            data = app.EditFieldWrite.Value;
            data = hex2dec(data);
            app.EditFieldWriteDecimal.Value = data;
            light_write_lamps(app, data);
        end

        % Value changed function: EditFieldWriteDecimal
        function EditFieldWriteDecimalValueChanged(app, event)
            data = app.EditFieldWriteDecimal.Value;
            app.EditFieldWrite.Value = dec2hex(data,4);
            light_write_lamps(app, data);
        end

        % Value changed function: ChipSelectDropDown
        function ChipSelectDropDownValueChanged(app, event)
            app.cs = str2double(app.ChipSelectDropDown.Value);
        end

        % Button pushed function: ReadButton
        function ReadButtonPushed(app, event)
            data = readNi845x(app.ni845x, app.cs, app.address);
            app.Read.Text = data;
            data = hex2dec(data);
            app.ReadDecimal.Text = num2str(data);
            light_read_lamps(app, data);
        end

        % Value changed function: ADDRESSEditField
        function ADDRESSEditFieldValueChanged(app, event)
            app.address = app.ADDRESSEditField.Value;
        end

        % Button pushed function: WriteButton
        function WriteButtonPushed(app, event)
            data = app.EditFieldWrite.Value;
            writeNi845x(app.ni845x, app.cs, app.address, data);
            app.Read.Text = data;
            data = hex2dec(data);
            app.ReadDecimal.Text = num2str(data);
            light_read_lamps(app, data);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Switch_0 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_0.Items = {'0', '1'};
            app.Switch_0.ItemsData = {'0', '1'};
            app.Switch_0.ValueChangedFcn = createCallbackFcn(app, @Switch_0ValueChanged, true);
            app.Switch_0.Value = '0';
            app.LampW_0 = uilamp(app.UIFigure);
            app.LampR_0 = uilamp(app.UIFigure);
            app.Label_0 = uilabel(app.UIFigure);
            app.Label_0.Text = '0';
            app.LampW_1 = uilamp(app.UIFigure);
            app.LampR_1 = uilamp(app.UIFigure);
            app.Label_1 = uilabel(app.UIFigure);
            app.Label_1.Text = '1';
            app.LampW_2 = uilamp(app.UIFigure);
            app.LampR_2 = uilamp(app.UIFigure);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '2';
            app.LampW_3 = uilamp(app.UIFigure);
            app.LampR_3 = uilamp(app.UIFigure);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '3';
            app.LampW_4 = uilamp(app.UIFigure);
            app.LampR_4 = uilamp(app.UIFigure);
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '4';
            app.LampW_5 = uilamp(app.UIFigure);
            app.LampR_5 = uilamp(app.UIFigure);
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '5';
            app.LampW_6 = uilamp(app.UIFigure);
            app.LampR_6 = uilamp(app.UIFigure);
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '6';
            app.LampW_7 = uilamp(app.UIFigure);
            app.LampR_7 = uilamp(app.UIFigure);
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '7';
            app.LampW_8 = uilamp(app.UIFigure);
            app.LampR_8 = uilamp(app.UIFigure);
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '8';
            app.LampW_9 = uilamp(app.UIFigure);
            app.LampR_9 = uilamp(app.UIFigure);
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '9';
            app.LampW_10 = uilamp(app.UIFigure);
            app.LampR_10 = uilamp(app.UIFigure);
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '10';
            app.LampW_11 = uilamp(app.UIFigure);
            app.LampR_11 = uilamp(app.UIFigure);
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '11';
            app.LampW_12 = uilamp(app.UIFigure);
            app.LampR_12 = uilamp(app.UIFigure);
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '12';
            app.LampW_13 = uilamp(app.UIFigure);
            app.LampR_13 = uilamp(app.UIFigure);
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = '13';
            app.LampW_14 = uilamp(app.UIFigure);
            app.LampR_14 = uilamp(app.UIFigure);
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Text = '14';
            app.LampW_15 = uilamp(app.UIFigure);
            app.LampR_15 = uilamp(app.UIFigure);
            app.Label_15 = uilabel(app.UIFigure);
            app.Label_15.Text = '15';
            app.ReadButton = uibutton(app.UIFigure, 'push');
            app.ReadButton.ButtonPushedFcn = createCallbackFcn(app, @ReadButtonPushed, true);
            app.ReadButton.Text = 'Read';
            app.FileWriteButton = uibutton(app.UIFigure, 'push');
            app.FileWriteButton.ButtonPushedFcn = createCallbackFcn(app, @FileWriteButtonPushed, true);
            app.FileWriteButton.Text = 'File Write';
            app.ChipSelectDropDownLabel = uilabel(app.UIFigure);
            app.ChipSelectDropDownLabel.Text = 'Chip Select';
            app.ChipSelectDropDown = uidropdown(app.UIFigure);
            app.ChipSelectDropDown.Items = {'RFIC', 'RC0A', 'RC0B', 'RC1B', 'RC1A', 'RESET'};
            app.ChipSelectDropDown.ItemsData = {'4', '0', '1', '2', '3', '5', ''};
            app.ChipSelectDropDown.ValueChangedFcn = createCallbackFcn(app, @ChipSelectDropDownValueChanged, true);
            app.ChipSelectDropDown.Value = '4';
            app.EditFieldWrite = uieditfield(app.UIFigure, 'text');
            app.EditFieldWrite.ValueChangedFcn = createCallbackFcn(app, @EditFieldWriteValueChanged, true);
            app.EditFieldWrite.Value = '0000';
            app.ADDRESSEditFieldLabel = uilabel(app.UIFigure);
            app.ADDRESSEditFieldLabel.Text = 'ADDRESS';
            app.ADDRESSEditField = uieditfield(app.UIFigure, 'text');
            app.ADDRESSEditField.ValueChangedFcn = createCallbackFcn(app, @ADDRESSEditFieldValueChanged, true);
            app.ADDRESSEditField.Value = '0000';
            app.DATALabel = uilabel(app.UIFigure);
            app.DATALabel.Text = 'DATA';
            app.RETURNLabel = uilabel(app.UIFigure);
            app.RETURNLabel.Text = 'RETURN';
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.Switch_1 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_1.Items = {'0', '1'};
            app.Switch_1.ItemsData = {'0', '1'};
            app.Switch_1.ValueChangedFcn = createCallbackFcn(app, @Switch_1ValueChanged, true);
            app.Switch_1.Value = '0';
            app.Switch_2 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_2.Items = {'0', '1'};
            app.Switch_2.ItemsData = {'0', '1'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = '0';
            app.Switch_3 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_3.Items = {'0', '1'};
            app.Switch_3.ItemsData = {'0', '1'};
            app.Switch_3.ValueChangedFcn = createCallbackFcn(app, @Switch_3ValueChanged, true);
            app.Switch_3.Value = '0';
            app.Switch_4 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_4.Items = {'0', '1'};
            app.Switch_4.ItemsData = {'0', '1'};
            app.Switch_4.ValueChangedFcn = createCallbackFcn(app, @Switch_4ValueChanged, true);
            app.Switch_4.Value = '0';
            app.Switch_5 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_5.Items = {'0', '1'};
            app.Switch_5.ItemsData = {'0', '1'};
            app.Switch_5.ValueChangedFcn = createCallbackFcn(app, @Switch_5ValueChanged, true);
            app.Switch_5.Value = '0';
            app.Switch_6 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_6.Items = {'0', '1'};
            app.Switch_6.ItemsData = {'0', '1'};
            app.Switch_6.ValueChangedFcn = createCallbackFcn(app, @Switch_6ValueChanged, true);
            app.Switch_6.Value = '0';
            app.Switch_7 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_7.Items = {'0', '1'};
            app.Switch_7.ItemsData = {'0', '1'};
            app.Switch_7.ValueChangedFcn = createCallbackFcn(app, @Switch_7ValueChanged, true);
            app.Switch_7.Value = '0';
            app.Switch_8 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_8.Items = {'0', '1'};
            app.Switch_8.ItemsData = {'0', '1'};
            app.Switch_8.ValueChangedFcn = createCallbackFcn(app, @Switch_8ValueChanged, true);
            app.Switch_8.Value = '0';
            app.Switch_9 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_9.Items = {'0', '1'};
            app.Switch_9.ItemsData = {'0', '1'};
            app.Switch_9.ValueChangedFcn = createCallbackFcn(app, @Switch_9ValueChanged, true);
            app.Switch_9.Value = '0';
            app.Switch_10 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_10.Items = {'0', '1'};
            app.Switch_10.ItemsData = {'0', '1'};
            app.Switch_10.ValueChangedFcn = createCallbackFcn(app, @Switch_10ValueChanged, true);
            app.Switch_10.Value = '0';
            app.Switch_11 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_11.Items = {'0', '1'};
            app.Switch_11.ItemsData = {'0', '1'};
            app.Switch_11.ValueChangedFcn = createCallbackFcn(app, @Switch_11ValueChanged, true);
            app.Switch_11.Value = '0';
            app.Switch_12 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_12.Items = {'0', '1'};
            app.Switch_12.ItemsData = {'0', '1'};
            app.Switch_12.ValueChangedFcn = createCallbackFcn(app, @Switch_12ValueChanged, true);
            app.Switch_12.Value = '0';
            app.Switch_13 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_13.Items = {'0', '1'};
            app.Switch_13.ItemsData = {'0', '1'};
            app.Switch_13.ValueChangedFcn = createCallbackFcn(app, @Switch_13ValueChanged, true);
            app.Switch_13.Value = '0';
            app.Switch_14 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_14.Items = {'0', '1'};
            app.Switch_14.ItemsData = {'0', '1'};
            app.Switch_14.ValueChangedFcn = createCallbackFcn(app, @Switch_14ValueChanged, true);
            app.Switch_14.Value = '0';
            app.Switch_15 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_15.Items = {'0', '1'};
            app.Switch_15.ItemsData = {'0', '1'};
            app.Switch_15.ValueChangedFcn = createCallbackFcn(app, @Switch_15ValueChanged, true);
            app.Switch_15.Value = '0';
            app.EditFieldWriteDecimal = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldWriteDecimal.ValueDisplayFormat = '%.0f';
            app.EditFieldWriteDecimal.ValueChangedFcn = createCallbackFcn(app, @EditFieldWriteDecimalValueChanged, true);
            app.Read = uilabel(app.UIFigure);
            app.Read.Text = '0000';
            app.ReadDecimal = uilabel(app.UIFigure);
            app.ReadDecimal.Text = '0';
            app.WriteButton = uibutton(app.UIFigure, 'push');
            app.WriteButton.ButtonPushedFcn = createCallbackFcn(app, @WriteButtonPushed, true);
            app.WriteButton.Text = 'Write';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = readWriteGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end